classdef bonus1_MUISC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        ThesecondsourcewithMUSICisTextArea_2Label  matlab.ui.control.Label
        ThesecondsourcewithMUSICisTextArea_2  matlab.ui.control.TextArea
        ThefirstsourcewithMUSICisTextArea_2Label  matlab.ui.control.Label
        ThefirstsourcewithMUSICisTextArea_2  matlab.ui.control.TextArea
        StopButton_2   matlab.ui.control.Button
        StartButton_2  matlab.ui.control.Button
    end


    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
    end

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            %这个函数会在计时器控制下每隔一段时间运行
            %这段到music算法前面的代码，是在录音
            deviceReader = audioDeviceReader;
            deviceReader.Device="麦克风 (USB YDB01 Audio Effect)";%设置麦克风
            deviceReader.NumChannels=4;%设置轨道数
            deviceReader.SampleRate=44100;%设置采样率
            setup(deviceReader);
           
            fileWriter = dsp.AudioFileWriter('mySpeech.wav','FileFormat','WAV');
         
            tic
            while toc < 4
                acquiredAudio = deviceReader();
                fileWriter(acquiredAudio);
            end
        
            release(deviceReader)
            release(fileWriter)

            [X,fs] = audioread("mySpeech.wav");
            X4=X(:,1);
            X3=X(:,2);
            X2=X(:,3);
            X1=X(:,4);

            X=cat(2,X1,X2,X3,X4);
            [N,nSensors] = size(X);                            
            J = nSensors;                                      
            dx = 0.025;                                            
            dy = 0;                                             
            c = 340;                                           
            n_source = 2;                                      
            Index = linspace(0,J-1,J);
            p = (-(J-1)/2 + Index.') * [dx dy];                    
            
            stride = 1 ;                                             
            theta = -90:stride:90;                                 
            v = [sin(theta*pi/180);-cos(theta*pi/180)];            
          
            winlen = 512;
            winmov = 256;
            ms = floor((N-winlen+winmov)/winmov)+1;
            Xs = zeros(ms,512,nSensors);
            win = hann(512);

            for i = 1:nSensors
                for j = 1:ms
                    try
                        Xs(j,:,i) = fft((X((1+256*(j-1)):(512+256*(j-1)),i).*win).');
                    catch
                    end
                end
            end

            P_sm = zeros(length(theta),1);
            for k = 1:winlen/2
                tempx = squeeze(Xs(:,k,:));
                tempfs = (k-1)/512*fs;
                P_sm = P_sm + MUSIC_nb(tempx,tempfs,p,v,n_source,c);
            end
            P_sm = 1./P_sm;

            
            P_middle = abs(P_sm(2:end-1));
            P_front = abs(P_sm(1:end-2));
            P_back = abs(P_sm(3:end));
            logic_front = (P_middle - P_front)>0;
            logic_back = (P_middle - P_back)>0;
            logic = logic_front & logic_back;
            P_middle(~logic) = min(P_middle);
            P_local = [abs(P_sm(1));P_middle;abs(P_sm(end))];
            [~,doa_Idx] = maxk(P_local,n_source);
            doa = theta(doa_Idx);
            [~,minIdx] = min(abs(doa));
            doa_source = doa(minIdx);
            [~,maxIdx] = max(abs(doa));
            interfer = doa(maxIdx);

            %这里一直到pax.ThetaTickLabel = {'-90','-60','-30','0','30','60','90'};是在绘制极坐标角度图
            Pmax = max(abs(P_sm));
            pax = polaraxes(app.Panel);
            polarplot(pax,deg2rad(theta+90),abs(P_sm)/Pmax);
            
            %这是是在设置极坐标坐标轴上的属性
            pax.ThetaLim = [0 180];
            pax.ThetaDir = 'counterclockwise';
            pax.FontSize = 12;
            pax.ThetaTickLabel = {'-90','-60','-30','0','30','60','90'};

            app.ThefirstsourcewithMUSICisTextArea_2.Value = [num2str(doa_source)];
            app.ThesecondsourcewithMUSICisTextArea_2.Value = [num2str(interfer)];

            function P_sm = MUSIC_nb(tempx,tempfs,p,v,n_source,c)                                   
                [tempN,~] = size(tempx);
                f_c = tempfs;
                R_x = (tempx.'*(tempx.')')/tempN;                                               
                a_theta = exp(-1j*2*pi*p*v/c*f_c);      
            
                [EV,~] = eig(R_x);     
                Un = EV(:,1:end-n_source);                                   
                P_sm = diag(a_theta'*(Un*Un')*a_theta);   

            end
          
          
       end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %这个函数是在设置app的初始设定
            %这里是在设置初始极坐标轴
          
            
            stride = 1 ;                                             
            theta = -90:stride:90;
            rho = zeros(1,181);
            pax = polaraxes(app.Panel);
            polarplot(pax,deg2rad(theta+90),rho)
            pax.ThetaLim = [0 180];
            pax.ThetaDir = 'counterclockwise';
            pax.FontSize = 12;
            pax.ThetaTickLabel = {'-90','-60','-30','0','30','60','90'};

            % 这里是在设置定时器
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period',5, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
        end

        % Callback function
        function StartButtonPushed(app, event)
         
        end

        % Callback function
        function StopButtonPushed(app, event)
 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.RandTimer);
            delete(app.RandTimer);
            delete(app);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % Stop the timer
            %这个函数会在按下stopbutton后运行
            %这个函数的作用时关闭计时器
            stop(app.RandTimer);
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
             % If timer is not running, start it
            %这个函数会在按下startbutton后运行
            %这个函数的作用时打开计时器
            if strcmp(app.RandTimer.Running, 'off')
               start(app.RandTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Random Number Generator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.StartButton_2 = uibutton(app.Panel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.Panel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.ThefirstsourcewithMUSICisTextArea_2 = uitextarea(app.Panel);
            app.ThefirstsourcewithMUSICisTextArea_2Label = uilabel(app.Panel);
            app.ThefirstsourcewithMUSICisTextArea_2Label.Text = 'The first source with MUSIC is';
            app.ThesecondsourcewithMUSICisTextArea_2 = uitextarea(app.Panel);
            app.ThesecondsourcewithMUSICisTextArea_2Label = uilabel(app.Panel);
            app.ThesecondsourcewithMUSICisTextArea_2Label.Text = 'The second source with MUSIC is';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bonus1_MUISC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end